classdef mainApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        EnvelopePanel                 matlab.ui.container.Panel
        CipherTextEditField           matlab.ui.control.TextArea
        CipherTextLabel               matlab.ui.control.Label
        TagLabel                      matlab.ui.control.TextArea
        TagTextAreaLabel              matlab.ui.control.Label
        IVErrorLabel                  matlab.ui.control.Label
        AuthStatusLabel_2             matlab.ui.control.Label
        CipherLengthLabel             matlab.ui.control.Label
        OutputTextArea                matlab.ui.control.TextArea
        OutputTextAreaLabel           matlab.ui.control.Label
        authStatus                    matlab.ui.control.Label
        cipherLen                     matlab.ui.control.Label
        ResetButton                   matlab.ui.control.Button
        DecryptButton                 matlab.ui.control.Button
        UnwrapDEKButton               matlab.ui.control.Button
        LoadEnvelopeButton            matlab.ui.control.Button
        SaveEnvelopeButton            matlab.ui.control.Button
        WrapDEKButton                 matlab.ui.control.Button
        EncryptButton                 matlab.ui.control.Button
        AADTextArea                   matlab.ui.control.TextArea
        AADLabel                      matlab.ui.control.Label
        PlainTextTextArea             matlab.ui.control.TextArea
        PlainTextTextAreaLabel        matlab.ui.control.Label
        InitKMSButton                 matlab.ui.control.Button
        IVEditField                   matlab.ui.control.EditField
        IVEditFieldLabel              matlab.ui.control.Label
        DiffHellPanel                 matlab.ui.container.Panel
        BobsComputeSecretKeyButton    matlab.ui.control.Button
        AlicesComputeSecretKeyButton  matlab.ui.control.Button
        BobSharedLabel                matlab.ui.control.Label
        AliceSharedLabel              matlab.ui.control.Label
        ComputedSharedKeyLabel_2      matlab.ui.control.Label
        ComputedSharedKeyLabel        matlab.ui.control.Label
        BobPublicLabel                matlab.ui.control.Label
        BobsPublicBLabel              matlab.ui.control.Label
        AlicePublicLabel              matlab.ui.control.Label
        AlicesPublicALabel            matlab.ui.control.Label
        PublishBtoAliceButton         matlab.ui.control.Button
        BobPrivateEditField           matlab.ui.control.NumericEditField
        BobPrivatebEditFieldLabel     matlab.ui.control.Label
        AlicePrivateEditField         matlab.ui.control.NumericEditField
        AlicePrivateaEditFieldLabel   matlab.ui.control.Label
        PublishAtoBobButton           matlab.ui.control.Button
        SetPublicParametersButton     matlab.ui.control.Button
        BobLabel                      matlab.ui.control.Label
        AliceLabel                    matlab.ui.control.Label
        gDHEditField                  matlab.ui.control.NumericEditField
        gEditFieldLabel               matlab.ui.control.Label
        pDHEditField                  matlab.ui.control.NumericEditField
        pEditField_2Label             matlab.ui.control.Label
        RSAPanel                      matlab.ui.container.Panel
        TextArea                      matlab.ui.control.TextArea
        LoadPrivateKeyButton          matlab.ui.control.Button
        GenerateKeysButton            matlab.ui.control.Button
        eDropDown                     matlab.ui.control.DropDown
        eDropDownLabel                matlab.ui.control.Label
        nLabel                        matlab.ui.control.Label
        qStatusLabel                  matlab.ui.control.Label
        pStatusLabel                  matlab.ui.control.Label
        qEditField                    matlab.ui.control.NumericEditField
        qEditFieldLabel               matlab.ui.control.Label
        pEditField                    matlab.ui.control.NumericEditField
        pEditFieldLabel               matlab.ui.control.Label
        HMACPanel                     matlab.ui.container.Panel
        HMACKeyEditField              matlab.ui.control.EditField
        HMACKeyEditFieldLabel         matlab.ui.control.Label
        DESPanel                      matlab.ui.container.Panel
        DESKeyTextArea                matlab.ui.control.TextArea
        DESKeyEditField               matlab.ui.control.EditField
        DESKeyLabel                   matlab.ui.control.Label
        OTPPanel                      matlab.ui.container.Panel
        OTPKeyEditField               matlab.ui.control.TextArea
        Label                         matlab.ui.control.Label
        OneTimePadKeyGeneratedRandomlyLabel  matlab.ui.control.Label
        ExitButton                    matlab.ui.control.Button
        MainMenuButton                matlab.ui.control.Button
        MainMenuPanel                 matlab.ui.container.Panel
        XORPanel                      matlab.ui.container.Panel
        XORKeyEditField               matlab.ui.control.EditField
        XORKeyLabel                   matlab.ui.control.Label
        CaesarPanel                   matlab.ui.container.Panel
        ShiftEditField                matlab.ui.control.NumericEditField
        ShiftEditFieldLabel           matlab.ui.control.Label
    end

    properties (Access = public)
        UI struct                     % dynamic UI handles per panel
        RSA struct
        DH struct
        Envelope struct
    
        currentText char = ''
        currentImage = []
        outputText char = ''
        outputImage = []
        currentKey char = ''
        currentModule char = ''

        isInputImage logical = false;   % default = text mode
    end

    
    methods (Access = public)
        %% Panel switching & utils
        function switchPanel(app, panelName)
            % Hide all panel-type properties and show the selected one
            props = properties(app);
            for i = 1:numel(props)
                n = props{i};
                try
                    if isa(app.(n),'matlab.ui.container.Panel')
                        app.(n).Visible = 'off';
                    end
                catch
                end
            end
            % show requested panel
            app.(panelName).Visible = 'on';
            app.currentModule = panelName;

            % reset module state when switching in
            resetModule(app,panelName);
            app.isInputImage=false;
            if(~strcmp(panelName,'MainMenuPanel'))
                app.UI.(panelName).TextRadio.Value = true;
                switchInputMode(app,'Text',panelName) %Text mode by default
            end

            updateStatus(app, "Ready");
            if strcmp(panelName,'EnvelopePanel')
                app.UI.(panelName).UserInput.Visible = 'off';
                app.UI.(panelName).UserOutput.Visible = 'off';
                app.UI.(panelName).Btn_Encrypt.Visible = 'off';
                app.UI.(panelName).Btn_Decrypt.Visible = 'off';
                updateKMSUIState(app);
            end
            
        end

        %% UI creation helpers (call at startup)
        function addPanels(app)
            

            uilabel(app.MainMenuPanel, 'Text','Select module:', 'Position',[20 340 200 22]);
            uibutton(app.MainMenuPanel, 'Text','Caesar Cipher', 'Position',[20 300 200 30], ...
                'ButtonPushedFcn', @(~,~)switchPanel(app,'CaesarPanel'));
            uibutton(app.MainMenuPanel, 'Text','One Time Pad', 'Position',[240 300 200 30], ...
                'ButtonPushedFcn', @(~,~)switchPanel(app,'OTPPanel'));
            uibutton(app.MainMenuPanel, 'Text','XOR (Text & Image)', 'Position',[460 300 200 30], ...
                'ButtonPushedFcn', @(~,~)switchPanel(app,'XORPanel'));
            uibutton(app.MainMenuPanel, 'Text','DES Block Cipher', 'Position',[20 250 200 30], ...
                'ButtonPushedFcn', @(~,~)switchPanel(app,'DESPanel'));
            uibutton(app.MainMenuPanel, 'Text','HMAC (SHA256)', 'Position',[240 250 200 30], ...
                 'ButtonPushedFcn', @(~,~)switchPanel(app,'HMACPanel'));
            uibutton(app.MainMenuPanel, 'Text','RSA', 'Position',[460 250 200 30], ...
                 'ButtonPushedFcn', @(~,~)switchPanel(app,'RSAPanel'));
            uibutton(app.MainMenuPanel, 'Text','Diffe Hellman Key Exchange', 'Position',[20 200 200 30], ...
                'ButtonPushedFcn', @(~,~)switchPanel(app,'DiffHellPanel'));
            uibutton(app.MainMenuPanel, 'Text','Envelope Encryption', 'Position',[240 200 200 30], ...
                 'ButtonPushedFcn', @(~,~)switchPanel(app,'EnvelopePanel'));

            % uibutton(app.MainMenuPanel, 'Text','XOR (Text & Image)', 'Position',[460 250 200 30], ...
            %     'ButtonPushedFcn', @(~,~)switchPanel(app,'XORPanel'));
        end 

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % add project subfolders to path so handlers/crypt/io/preview/components are visible
            root = fileparts(mfilename('fullpath'));
            addpath(fullfile(root,'components'));
            addpath(fullfile(root,'crypt'));
            addpath(fullfile(root,'crypt','des'));
            addpath(fullfile(root,'crypt','rsa'));
            addpath(fullfile(root,'crypt','diffie-hellman'));
            addpath(fullfile(root,'crypt','envelope'));
            addpath(fullfile(root,'crypt','envelope','AES_DHill'));
            addpath(fullfile(root,'crypt','envelope','GCM_BlockCipher'));
            addpath(fullfile(root,'handlers'));
            addpath(fullfile(root,'io'));
            addpath(fullfile(root,'io','encoding'));
        
            % initialize namespace
            app.UI = struct();
            app.RSA = struct( ...
                'p',[], 'q',[], 'n',[], 'phi',[], ...
                'e',[], 'd',[], ...
                'hasPublicKey',false, ...
                'hasPrivateKey',false);
            app.DH = struct('p',[],'g',[],'a',[],'b',[],'A',[],'B',[],'S_A',[],'S_B',[]);
            app.Envelope = struct( ...
                'plainTextInput','', ...
                'iv','', ...
                'aad','', ...
                'DEK',[], ...
                'ciphertext','', ...
                'tag','', ...
                'wrappedDEK',[], ...
                'kms',[], ...
                'envelope',[], ...
                'plainTextRecovered','', ...
                'authOK',false ...
            );


            % Create static panels and main menu
            addPanels(app);

            % create reusable UI inside panels
            addReusableIOComponents(app,'CaesarPanel', false); % text only
            addReusableIOComponents(app,'OTPPanel',false);
            addReusableIOComponents(app,'XORPanel', true);     % text and image
            addReusableIOComponents(app,'DESPanel', true);     % text and image
            addReusableIOComponents(app,'HMACPanel',false);
            addReusableIOComponents(app,'RSAPanel',false);
            addReusableIOComponents(app,'DiffHellPanel',false);
            % addReusableIOComponents(app,'EnvelopePanel',false);

            % show Main Menu first
            switchPanel(app,'MainMenuPanel');

        end

        % Button pushed function: MainMenuButton
        function MainMenuButtonPushed(app, event)
            switchPanel(app, 'MainMenuPanel')
        end

        % Value changed function: XORKeyEditField
        function XORKeyEditFieldValueChanged(app, event)
            value = app.XORKeyEditField.Value;
            app.currentKey = value;
        end

        % Button pushed function: ExitButton
        function ExitButtonPushed(app, event)
            delete(app)
        end

        % Value changed function: pEditField
        function pEditFieldValueChanged(app, event)
            value = app.pEditField.Value;
            validateRSAInputs(app);
        end

        % Value changed function: qEditField
        function qEditFieldValueChanged(app, event)
            value = app.qEditField.Value;
            validateRSAInputs(app);
        end

        % Button pushed function: GenerateKeysButton
        function GenerateKeysButtonPushed(app, event)
            generateRSAKeys(app);
        end

        % Button pushed function: LoadPrivateKeyButton
        function LoadPrivateKeyButtonPushed(app, event)
            loadRSAKeys(app);
        end

        % Button pushed function: SetPublicParametersButton
        function SetPublicParametersButtonPushed(app, event)
            SetPublicParams(app);
        end

        % Button pushed function: PublishAtoBobButton
        function PublishAtoBobButtonPushed(app, event)
            ExchangeAlice(app);
        end

        % Button pushed function: PublishBtoAliceButton
        function PublishBtoAliceButtonPushed(app, event)
            ExchangeBob(app);
        end

        % Button pushed function: AlicesComputeSecretKeyButton
        function AlicesComputeSecretKeyButtonPushed(app, event)
            ComputeSharedAlice(app);
        end

        % Button pushed function: BobsComputeSecretKeyButton
        function BobsComputeSecretKeyButtonPushed(app, event)
            ComputeSharedBob(app);
        end

        % Value changed function: PlainTextTextArea
        function PlainTextTextAreaValueChanged(app, event)
         
            app.Envelope.plainTextInput = normalizeTextInput(app.PlainTextTextArea.Value);

            updateKMSUIState(app);
        end

        % Value changed function: IVEditField
        function IVEditFieldValueChanged(app, event)
            
            app.Envelope.iv = lower(app.IVEditField.Value);
            updateKMSUIState(app);
        end

        % Value changed function: AADTextArea
        function AADTextAreaValueChanged(app, event)
            
            app.Envelope.aad = normalizeTextInput(app.AADTextArea.Value);
            updateKMSUIState(app);
        end

        % Button pushed function: InitKMSButton
        function InitKMSButtonPushed(app, event)
            app.Envelope = kms_gui_flow('init_kms', app.Envelope);
            updateKMSUIState(app);
        end

        % Button pushed function: EncryptButton
        function EncryptButtonPushed(app, event)
            app.Envelope.plainTextInput = normalizeTextInput(app.PlainTextTextArea.Value);
            app.Envelope.iv  = lower(app.IVEditField.Value);
            app.Envelope.aad = normalizeTextInput(app.AADTextArea.Value);
            
            app.Envelope = kms_gui_flow('encrypt_inner', app.Envelope);
            
            app.CipherTextEditField.Value = app.Envelope.ciphertext;
            app.TagLabel.Value = app.Envelope.tag;
            app.cipherLen.Text = string(numel(app.Envelope.ciphertext)/2);
            
            updateKMSUIState(app);

        end

        % Button pushed function: WrapDEKButton
        function WrapDEKButtonPushed(app, event)
            app.Envelope = kms_gui_flow('wrap_dek', app.Envelope);
            updateKMSUIState(app);
        end

        % Button pushed function: SaveEnvelopeButton
        function SaveEnvelopeButtonPushed(app, event)
            envelope = app.Envelope.envelope;
            save('envelope.mat','envelope');
        end

        % Button pushed function: LoadEnvelopeButton
        function LoadEnvelopeButtonPushed(app, event)
            load('envelope.mat','envelope');
            app.Envelope.envelope = envelope;
            updateKMSUIState(app);

        end

        % Button pushed function: UnwrapDEKButton
        function UnwrapDEKButtonPushed(app, event)
            app.Envelope = kms_gui_flow('unwrap_dek', app.Envelope);
            updateKMSUIState(app);
        end

        % Button pushed function: DecryptButton
        function DecryptButtonPushed(app, event)
            app.Envelope = kms_gui_flow('decrypt', app.Envelope);
            
            app.OutputTextArea.Value = char(app.Envelope.decryptedText);
            app.authStatus.Text = "Auth OK: " + string(app.Envelope.authOK);

        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            app.Envelope = struct( ...
                'plainTextInput','', ...
                'iv','', ...
                'aad','', ...
                'DEK',[], ...
                'wrappedDEK',[], ...
                'ciphertext','', ...
                'tag','', ...
                'kms',[], ...
                'envelope',[] ...
            );
            app.PlainTextTextArea.Value = '';
            app.OutputTextArea.Value = '';
            app.IVEditField.Value = '';
            app.AADTextArea.Value = '';
            app.TagLabel.Value = '';
            app.cipherLen.Text = '';
            app.authStatus.Text = '';
            updateKMSUIState(app);

        end

        % Value changed function: CipherTextEditField
        function CipherTextEditFieldValueChanged(app, event)
            value = app.CipherTextEditField.Value;
            app.Envelope.ciphertext = value;
        end

        % Value changed function: TagLabel
        function TagLabelValueChanged(app, event)
            value = app.TagLabel.Value;
            app.Envelope.tag = value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.CaesarPanel = uipanel(app.UIFigure);
            app.CaesarPanel.Title = 'Caesar';
            app.CaesarPanel.Visible = 'off';
            app.ShiftEditFieldLabel = uilabel(app.CaesarPanel);
            app.ShiftEditFieldLabel.Text = 'Shift';
            app.ShiftEditField = uieditfield(app.CaesarPanel, 'numeric');
            app.ShiftEditField.Limits = [1 25];
            app.ShiftEditField.ValueDisplayFormat = '%.0f';
            app.ShiftEditField.Value = 3;
            app.XORPanel = uipanel(app.UIFigure);
            app.XORPanel.Title = 'XOR';
            app.XORPanel.Visible = 'off';
            app.XORKeyLabel = uilabel(app.XORPanel);
            app.XORKeyLabel.Text = 'XOR Key';
            app.XORKeyEditField = uieditfield(app.XORPanel, 'text');
            app.XORKeyEditField.ValueChangedFcn = createCallbackFcn(app, @XORKeyEditFieldValueChanged, true);
            app.MainMenuPanel = uipanel(app.UIFigure);
            app.MainMenuPanel.Title = 'Main Menu';
            app.MainMenuPanel.Visible = 'off';
            app.MainMenuButton = uibutton(app.UIFigure, 'push');
            app.MainMenuButton.ButtonPushedFcn = createCallbackFcn(app, @MainMenuButtonPushed, true);
            app.MainMenuButton.Text = 'Main Menu';
            app.ExitButton = uibutton(app.UIFigure, 'push');
            app.ExitButton.ButtonPushedFcn = createCallbackFcn(app, @ExitButtonPushed, true);
            app.ExitButton.Text = 'Exit';
            app.OTPPanel = uipanel(app.UIFigure);
            app.OTPPanel.Title = 'OTP';
            app.OTPPanel.Visible = 'off';
            app.OneTimePadKeyGeneratedRandomlyLabel = uilabel(app.OTPPanel);
            app.OneTimePadKeyGeneratedRandomlyLabel.Text = 'One Time Pad Key (Generated Randomly)';
            app.Label = uilabel(app.OTPPanel);
            app.Label.Text = '';
            app.OTPKeyEditField = uitextarea(app.OTPPanel);
            app.OTPKeyEditField.Editable = 'off';
            app.DESPanel = uipanel(app.UIFigure);
            app.DESPanel.Title = 'DES';
            app.DESPanel.Visible = 'off';
            app.DESKeyLabel = uilabel(app.DESPanel);
            app.DESKeyLabel.Text = 'DES Key';
            app.DESKeyEditField = uieditfield(app.DESPanel, 'text');
            app.DESKeyEditField.CharacterLimits = [0 7];
            app.DESKeyEditField.Placeholder = '(0 or 7 chars only)';
            app.DESKeyTextArea = uitextarea(app.DESPanel);
            app.DESKeyTextArea.Editable = 'off';
            app.DESKeyTextArea.Placeholder = ' 8×8 bit key matrix                 (last col = parity bits)';
            app.HMACPanel = uipanel(app.UIFigure);
            app.HMACPanel.Title = 'HMAC';
            app.HMACPanel.Visible = 'off';
            app.HMACKeyEditFieldLabel = uilabel(app.HMACPanel);
            app.HMACKeyEditFieldLabel.Text = 'HMAC Key';
            app.HMACKeyEditField = uieditfield(app.HMACPanel, 'text');
            app.RSAPanel = uipanel(app.UIFigure);
            app.RSAPanel.Title = 'RSA';
            app.RSAPanel.Visible = 'off';
            app.pEditFieldLabel = uilabel(app.RSAPanel);
            app.pEditFieldLabel.Text = 'p';
            app.pEditField = uieditfield(app.RSAPanel, 'numeric');
            app.pEditField.Limits = [17 Inf];
            app.pEditField.RoundFractionalValues = 'on';
            app.pEditField.ValueDisplayFormat = '%.0f';
            app.pEditField.ValueChangedFcn = createCallbackFcn(app, @pEditFieldValueChanged, true);
            app.pEditField.Value = 17;
            app.qEditFieldLabel = uilabel(app.RSAPanel);
            app.qEditFieldLabel.Text = 'q';
            app.qEditField = uieditfield(app.RSAPanel, 'numeric');
            app.qEditField.Limits = [19 Inf];
            app.qEditField.RoundFractionalValues = 'on';
            app.qEditField.ValueDisplayFormat = '%.0f';
            app.qEditField.ValueChangedFcn = createCallbackFcn(app, @qEditFieldValueChanged, true);
            app.qEditField.Value = 19;
            app.pStatusLabel = uilabel(app.RSAPanel);
            app.pStatusLabel.Text = 'pStatus';
            app.qStatusLabel = uilabel(app.RSAPanel);
            app.qStatusLabel.Text = 'qStatus';
            app.nLabel = uilabel(app.RSAPanel);
            app.nLabel.Text = 'n = _____';
            app.eDropDownLabel = uilabel(app.RSAPanel);
            app.eDropDownLabel.Text = 'e';
            app.eDropDown = uidropdown(app.RSAPanel);
            app.eDropDown.Items = {};
            app.eDropDown.Value = {};
            app.GenerateKeysButton = uibutton(app.RSAPanel, 'push');
            app.GenerateKeysButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateKeysButtonPushed, true);
            app.GenerateKeysButton.Enable = 'off';
            app.GenerateKeysButton.Text = 'GenerateKeys';
            app.LoadPrivateKeyButton = uibutton(app.RSAPanel, 'push');
            app.LoadPrivateKeyButton.ButtonPushedFcn = createCallbackFcn(app, @LoadPrivateKeyButtonPushed, true);
            app.LoadPrivateKeyButton.Text = 'Load Private Key';
            app.TextArea = uitextarea(app.RSAPanel);
            app.TextArea.Editable = 'off';
            app.TextArea.Value = {'(p,q > =17'; 'to ensure '; '0<M<n)'; 'for ASCII encoded M'; ''; 'List of Primes:'; ''; '17, 19, 23, 29, 31, 37, 41, 43, 47, 53, 59, 61, 67, 71, 73, 79, 83, 89, 97,101,103, 107, 109, 113, 127, 131, 137, 139, 149, 151, 157, 163, 167, 173, 179, 181, 191, 193, 197, 199... and so on'};
            app.DiffHellPanel = uipanel(app.UIFigure);
            app.DiffHellPanel.Title = 'Diffe Hellman';
            app.DiffHellPanel.Visible = 'off';
            app.pEditField_2Label = uilabel(app.DiffHellPanel);
            app.pEditField_2Label.Text = 'p';
            app.pDHEditField = uieditfield(app.DiffHellPanel, 'numeric');
            app.pDHEditField.Limits = [0 Inf];
            app.pDHEditField.RoundFractionalValues = 'on';
            app.pDHEditField.ValueDisplayFormat = '%.0f';
            app.gEditFieldLabel = uilabel(app.DiffHellPanel);
            app.gEditFieldLabel.Text = 'g';
            app.gDHEditField = uieditfield(app.DiffHellPanel, 'numeric');
            app.gDHEditField.Limits = [0 Inf];
            app.gDHEditField.RoundFractionalValues = 'on';
            app.gDHEditField.ValueDisplayFormat = '%.0f';
            app.AliceLabel = uilabel(app.DiffHellPanel);
            app.AliceLabel.Text = 'Alice';
            app.BobLabel = uilabel(app.DiffHellPanel);
            app.BobLabel.Text = 'Bob';
            app.SetPublicParametersButton = uibutton(app.DiffHellPanel, 'push');
            app.SetPublicParametersButton.ButtonPushedFcn = createCallbackFcn(app, @SetPublicParametersButtonPushed, true);
            app.SetPublicParametersButton.Text = 'Set Public Parameters';
            app.PublishAtoBobButton = uibutton(app.DiffHellPanel, 'push');
            app.PublishAtoBobButton.ButtonPushedFcn = createCallbackFcn(app, @PublishAtoBobButtonPushed, true);
            app.PublishAtoBobButton.Text = 'Publish A to Bob';
            app.AlicePrivateaEditFieldLabel = uilabel(app.DiffHellPanel);
            app.AlicePrivateaEditFieldLabel.Text = 'Alice Private a';
            app.AlicePrivateEditField = uieditfield(app.DiffHellPanel, 'numeric');
            app.AlicePrivateEditField.Limits = [0 Inf];
            app.AlicePrivateEditField.RoundFractionalValues = 'on';
            app.AlicePrivateEditField.ValueDisplayFormat = '%.0f';
            app.BobPrivatebEditFieldLabel = uilabel(app.DiffHellPanel);
            app.BobPrivatebEditFieldLabel.Text = 'Bob Private b';
            app.BobPrivateEditField = uieditfield(app.DiffHellPanel, 'numeric');
            app.BobPrivateEditField.Limits = [0 Inf];
            app.BobPrivateEditField.RoundFractionalValues = 'on';
            app.BobPrivateEditField.ValueDisplayFormat = '%.0f';
            app.PublishBtoAliceButton = uibutton(app.DiffHellPanel, 'push');
            app.PublishBtoAliceButton.ButtonPushedFcn = createCallbackFcn(app, @PublishBtoAliceButtonPushed, true);
            app.PublishBtoAliceButton.Text = 'Publish B to Alice';
            app.AlicesPublicALabel = uilabel(app.DiffHellPanel);
            app.AlicesPublicALabel.Text = 'Alice''s Public A: ';
            app.AlicePublicLabel = uilabel(app.DiffHellPanel);
            app.AlicePublicLabel.Text = '';
            app.BobsPublicBLabel = uilabel(app.DiffHellPanel);
            app.BobsPublicBLabel.Text = 'Bob''s Public B: ';
            app.BobPublicLabel = uilabel(app.DiffHellPanel);
            app.BobPublicLabel.Text = '';
            app.ComputedSharedKeyLabel = uilabel(app.DiffHellPanel);
            app.ComputedSharedKeyLabel.Text = 'Computed Shared Key: ';
            app.ComputedSharedKeyLabel_2 = uilabel(app.DiffHellPanel);
            app.ComputedSharedKeyLabel_2.Text = 'Computed Shared Key: ';
            app.AliceSharedLabel = uilabel(app.DiffHellPanel);
            app.AliceSharedLabel.Text = '';
            app.BobSharedLabel = uilabel(app.DiffHellPanel);
            app.BobSharedLabel.Text = '';
            app.AlicesComputeSecretKeyButton = uibutton(app.DiffHellPanel, 'push');
            app.AlicesComputeSecretKeyButton.ButtonPushedFcn = createCallbackFcn(app, @AlicesComputeSecretKeyButtonPushed, true);
            app.AlicesComputeSecretKeyButton.Text = 'Alice''s Compute Secret Key';
            app.BobsComputeSecretKeyButton = uibutton(app.DiffHellPanel, 'push');
            app.BobsComputeSecretKeyButton.ButtonPushedFcn = createCallbackFcn(app, @BobsComputeSecretKeyButtonPushed, true);
            app.BobsComputeSecretKeyButton.Text = 'Bob''s Compute Secret Key';
            app.EnvelopePanel = uipanel(app.UIFigure);
            app.EnvelopePanel.Title = 'Envelope Encryption';
            app.IVEditFieldLabel = uilabel(app.EnvelopePanel);
            app.IVEditFieldLabel.Text = 'IV';
            app.IVEditField = uieditfield(app.EnvelopePanel, 'text');
            app.IVEditField.ValueChangedFcn = createCallbackFcn(app, @IVEditFieldValueChanged, true);
            app.InitKMSButton = uibutton(app.EnvelopePanel, 'push');
            app.InitKMSButton.ButtonPushedFcn = createCallbackFcn(app, @InitKMSButtonPushed, true);
            app.InitKMSButton.Text = 'Init KMS';
            app.PlainTextTextAreaLabel = uilabel(app.EnvelopePanel);
            app.PlainTextTextAreaLabel.Text = 'Plain Text';
            app.PlainTextTextArea = uitextarea(app.EnvelopePanel);
            app.PlainTextTextArea.ValueChangedFcn = createCallbackFcn(app, @PlainTextTextAreaValueChanged, true);
            app.AADLabel = uilabel(app.EnvelopePanel);
            app.AADLabel.Text = 'AAD';
            app.AADTextArea = uitextarea(app.EnvelopePanel);
            app.AADTextArea.ValueChangedFcn = createCallbackFcn(app, @AADTextAreaValueChanged, true);
            app.EncryptButton = uibutton(app.EnvelopePanel, 'push');
            app.EncryptButton.ButtonPushedFcn = createCallbackFcn(app, @EncryptButtonPushed, true);
            app.EncryptButton.Text = 'Encrypt';
            app.WrapDEKButton = uibutton(app.EnvelopePanel, 'push');
            app.WrapDEKButton.ButtonPushedFcn = createCallbackFcn(app, @WrapDEKButtonPushed, true);
            app.WrapDEKButton.Text = 'Wrap DEK';
            app.SaveEnvelopeButton = uibutton(app.EnvelopePanel, 'push');
            app.SaveEnvelopeButton.ButtonPushedFcn = createCallbackFcn(app, @SaveEnvelopeButtonPushed, true);
            app.SaveEnvelopeButton.Text = 'Save Envelope';
            app.LoadEnvelopeButton = uibutton(app.EnvelopePanel, 'push');
            app.LoadEnvelopeButton.ButtonPushedFcn = createCallbackFcn(app, @LoadEnvelopeButtonPushed, true);
            app.LoadEnvelopeButton.Text = 'Load Envelope';
            app.UnwrapDEKButton = uibutton(app.EnvelopePanel, 'push');
            app.UnwrapDEKButton.ButtonPushedFcn = createCallbackFcn(app, @UnwrapDEKButtonPushed, true);
            app.UnwrapDEKButton.Text = 'Unwrap DEK';
            app.DecryptButton = uibutton(app.EnvelopePanel, 'push');
            app.DecryptButton.ButtonPushedFcn = createCallbackFcn(app, @DecryptButtonPushed, true);
            app.DecryptButton.Text = 'Decrypt';
            app.ResetButton = uibutton(app.EnvelopePanel, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.cipherLen = uilabel(app.EnvelopePanel);
            app.cipherLen.Text = '';
            app.authStatus = uilabel(app.EnvelopePanel);
            app.authStatus.Text = '';
            app.OutputTextAreaLabel = uilabel(app.EnvelopePanel);
            app.OutputTextAreaLabel.Text = 'Output';
            app.OutputTextArea = uitextarea(app.EnvelopePanel);
            app.CipherLengthLabel = uilabel(app.EnvelopePanel);
            app.CipherLengthLabel.Text = {'Cipher '; 'Length:'};
            app.AuthStatusLabel_2 = uilabel(app.EnvelopePanel);
            app.AuthStatusLabel_2.Text = {'Auth'; 'Status'};
            app.IVErrorLabel = uilabel(app.EnvelopePanel);
            app.IVErrorLabel.Text = '';
            app.TagTextAreaLabel = uilabel(app.EnvelopePanel);
            app.TagTextAreaLabel.Text = 'Tag';
            app.TagLabel = uitextarea(app.EnvelopePanel);
            app.TagLabel.ValueChangedFcn = createCallbackFcn(app, @TagLabelValueChanged, true);
            app.TagLabel.Editable = 'off';
            app.CipherTextLabel = uilabel(app.EnvelopePanel);
            app.CipherTextLabel.Text = {'Cipher'; 'Text'};
            app.CipherTextEditField = uitextarea(app.EnvelopePanel);
            app.CipherTextEditField.ValueChangedFcn = createCallbackFcn(app, @CipherTextEditFieldValueChanged, true);
            app.CipherTextEditField.Editable = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = mainApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end